classdef OxygenDynamics_Sinks_Curation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PocketsUIFigure         matlab.ui.Figure
        StatusEditField         matlab.ui.control.EditField
        IsthisarealpocketLabel  matlab.ui.control.Label
        NoButton                matlab.ui.control.Button
        YesButton               matlab.ui.control.Button
        ExportDataButton        matlab.ui.control.Button
        PocketsPanel            matlab.ui.container.Panel
        NextButton              matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        PocketListBox           matlab.ui.control.ListBox
        LoadDataButton          matlab.ui.control.Button
        PocketsLabel            matlab.ui.control.Label
        Pocketpos               matlab.ui.control.UIAxes
        Ztrace                  matlab.ui.control.UIAxes
        Notrendtrace            matlab.ui.control.UIAxes
    end

    
    properties (Access = private) % the properties are used by the callbacks to share data
        pocdata % Description
    end
    
    methods (Access = private)
        % function for the "previous" button
        function previous_pocket(app)
            [~, cur_poc] = ismember(app.PocketListBox.Value, app.PocketListBox.Items);
            if cur_poc == 1
                return
            else
                app.PocketListBox.Value = app.PocketListBox.Items(cur_poc - 1); drawnow
            end
            view_pocket(app)
        end
        
        % function for the "next" button
        function next_pocket(app)
            [~, cur_poc] = ismember(app.PocketListBox.Value, app.PocketListBox.Items);
            if cur_poc == length(app.PocketListBox.Items)
                return
            else
                app.PocketListBox.Value = app.PocketListBox.Items(cur_poc + 1); drawnow
            end
            view_pocket(app)
        end
        
        % function for the "yes" button 
        function include_pocket(app)
            [~, cur_poc] = ismember(app.PocketListBox.Value, app.PocketListBox.Items);
            app.PocketListBox.Items(cur_poc) = {num2str(cur_poc)};
            app.PocketListBox.Value = app.PocketListBox.Items(cur_poc);
            drawnow
            view_pocket(app)
        end
        
        % function for the "no" button 
        function exclude_pocket(app)
            [~, cur_poc] = ismember(app.PocketListBox.Value, app.PocketListBox.Items);
            app.PocketListBox.Items(cur_poc) = {[num2str(cur_poc) ' X']};
            app.PocketListBox.Value = app.PocketListBox.Items(cur_poc);
            drawnow
            view_pocket(app)
        end
        
        function view_pocket(app)

            [~, cur_poc] = ismember(app.PocketListBox.Value, app.PocketListBox.Items);
            
            % Plot convolved trace
            plot(app.Ztrace, app.pocdata.Mean_OxySink_Trace_Convo(cur_poc, :))
            
            %mark the oxygen drop events on the trace
            hold(app.Ztrace,'on')
            for i=1:length(app.pocdata.Table_OxygenSinks_Out.Start{cur_poc})   
                plot(app.Ztrace,...
                    app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i):app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i)+...
                    app.pocdata.Table_OxygenSinks_Out.Duration{cur_poc}(i)-1,...
                    app.pocdata.Mean_OxySink_Trace_Convo(cur_poc,app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i):app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i)+...
                    app.pocdata.Table_OxygenSinks_Out.Duration{cur_poc}(i)-1),'r','LineWidth',1.5)
            end

            hold(app.Ztrace,'off')
            
            %plot the z-scored trace
            plot(app.Notrendtrace, app.pocdata.Mean_OxySink_TraceZ(cur_poc, :))
            
            %mark the oxygen drop events on the trace
            hold(app.Notrendtrace,'on')
            for i=1:length(app.pocdata.Table_OxygenSinks_Out.Start{cur_poc})   
                plot(app.Notrendtrace,...
                    app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i):app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i)+...
                    app.pocdata.Table_OxygenSinks_Out.Duration{cur_poc}(i)-1,...
                    app.pocdata.Mean_OxySink_TraceZ(cur_poc,app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i):app.pocdata.Table_OxygenSinks_Out.Start{cur_poc}(i)+...
                    app.pocdata.Table_OxygenSinks_Out.Duration{cur_poc}(i)-1),'r','LineWidth',1.5)
            end

            hold(app.Notrendtrace,'off')

            % Display whole field map with bounding box every time I move to a different pocket
            % whole_field_size = round(getpixelposition(app.Pocketpos));
%             figure('visible', 'off');imagesc(app.Pocketpos,imresize(app.pocdata.Pockets_trim, [whole_field_size(4), whole_field_size(4)], 'nearest'));hold on
%             set(gca, 'Units', 'pixels', 'Position', [0, 0, whole_field_size(4), whole_field_size(4)]);
%             set(gca, 'xtick', [], 'xticklabel', [], 'ytick', [], 'yticklabel', [], 'box', 'off')
%             
            figure('visible', 'off');
            imagesc(app.Pocketpos,app.pocdata.OxySink_Map); 

            %hold(app.Pocketpos, 'on');
            set(gca, 'xtick', [], 'xticklabel', [], 'ytick', [], 'yticklabel', [], 'box', 'off')
            rectangle(app.Pocketpos,'Position',app.pocdata.Table_OxygenSinks_Out.MeanOxySinkBoundingBox{cur_poc}, 'EdgeColor','r','LineWidth',2);
            %hold(app.Pocketpos, 'off')
            

            % Display exclusion status
            cur_roi_name = app.PocketListBox.Items(cur_poc);
            if contains(cur_roi_name, 'X') % checks if marked excluded
                app.StatusEditField.Value = ['ROI #' num2str(cur_poc) ' - excluded'];
                app.StatusEditField.FontColor = [0 0 0];
                app.StatusEditField.BackgroundColor = [ 0.8706 0.0941 0.2392];
            else
                app.StatusEditField.Value = ['ROI #' num2str(cur_poc) ' - included'];
                app.StatusEditField.FontColor = [0 0 0];
                app.StatusEditField.BackgroundColor = [0.3922 0.8314 0.0745];
            end
            

            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.Pocketpos)
            disableDefaultInteractivity(app.Ztrace)
            disableDefaultInteractivity(app.Notrendtrace)         
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [add_file, add_filepath] = uigetfile('*.mat', 'Choose data file to be processed.', 'MultiSelect', 'off');
            %figure(app.PocketsUIFigure) % workaround to keep focus at UI window
            % add_file and add_filepath have the path and the name of the file
            if add_file == 0
                return
            end
         
            full_filepath = [add_filepath add_file];
            app.pocdata = load(full_filepath);
            app.pocdata.path = full_filepath;
            
            if ~isfield(app.pocdata, 'Table_OxygenSinks_Out') % check to make sure data are compatible and raw data available
                warning_text = 'No hypoxic pocket data found in the selected file. Did you load a ManualCuration mat file?';
                msgbox(warning_text, 'Warning');
                return
            end
            
          
            app.pocdata.map_size = size(app.pocdata.OxySink_Map);
            app.pocdata.num_of_poc = height(app.pocdata.Table_OxygenSinks_Out);
            app.pocdata.num_of_frames = size(app.pocdata.Mean_OxySink_TraceZ, 2);
            
            % populate the list of Pockets
            app.PocketListBox.Items = cellstr(string(1:app.pocdata.num_of_poc)); 
            
            % Update stats of first Pockets on GUI
            app.PocketListBox.Value = '1'; drawnow % set selection to first Pocket
            
            %include marks for suspected noise sinks
            for i=1:length(app.PocketListBox.Items)               
                if ~app.pocdata.Trace_PotentialNoise(i)                            
                    app.PocketListBox.Items(i) = {[num2str(i) ' X']};
                end
            end
            
            %run the function that show me the elements on the GUI
            view_pocket(app)
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            previous_pocket(app)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            next_pocket(app)
        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            include_pocket(app)
            next_pocket(app)            
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            exclude_pocket(app)
            next_pocket(app)
        end

        % Value changed function: PocketListBox
        function PocketListBoxValueChanged(app, event)
            view_pocket(app)
            
        end

        % Close request function: PocketsUIFigure
        function PocketsUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Key press function: PocketsUIFigure
        function PocketsUIFigureKeyPress(app, event)
            switch event.Key
                case 'e'
                    if contains(app.PocketListBox.Value, 'X')
                        include_pocket(app)
                        next_pocket(app)
                        view_pocket(app)
                    else
                        exclude_pocket(app)
                        next_pocket(app)
                        view_pocket(app)
                    end
                case 'uparrow'
                    previous_pocket(app)                    
                case 'downarrow'
                    next_pocket(app)
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            if isempty(app.PocketListBox.Items)
                warning_text = 'No Pocket data file has been loaded yet!';
                msgbox(warning_text, 'Warning');
                return
            end
            
            % disable run button during saving
            app.ExportDataButton.Enable = 'off'; drawnow
            
            % Get the logical vector that filters out the false pockets
            app.pocdata.included_Pocs = true(length(app.PocketListBox.Items),1);
            for i = 1:length(app.PocketListBox.Items)
                if contains(app.PocketListBox.Items(i), 'X')
                    app.pocdata.included_Pocs(i) = false;
                end
            end
            
            Included_Pocs = app.pocdata.included_Pocs;
            Table_OxygenSinks_Out_currated = addvars(app.pocdata.Table_OxygenSinks_Out,Included_Pocs);
            
           
            % Save data
            filename_mat = app.pocdata.path;

                if ismember('Table_OxygenSinks_Out_currated', who('-file', filename_mat))
                    msgbox(['It looks like file ' filename_mat ' already have saved oxygen sinks refinement data. Will improvise a different file name to avoid overwriting.'], 'Warning')
                    filename_mat = [filename_mat(1:end-4) '_' datestr(now, 30) '.mat'];
                    save(filename_mat, 'Included_Pocs', 'Table_OxygenSinks_Out_currated');
                else
                    save(filename_mat, 'Included_Pocs', 'Table_OxygenSinks_Out_currated', '-append');
                end
             

            app.ExportDataButton.Enable = 'on'; drawnow
            
            disp('Data exported successfully.')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PocketsUIFigure = uifigure('Visible', 'off');
            app.PocketsUIFigure.Name = 'MATLAB App';
            app.PocketsUIFigure.CloseRequestFcn = createCallbackFcn(app, @PocketsUIFigureCloseRequest, true);
            app.PocketsUIFigure.KeyPressFcn = createCallbackFcn(app, @PocketsUIFigureKeyPress, true);
            app.Notrendtrace = uiaxes(app.PocketsUIFigure);
            title(app.Notrendtrace, 'Detrended mean oxygen sink trace (z)')
            app.Notrendtrace.XTick = [];
            app.Notrendtrace.YTick = [];
            app.Ztrace = uiaxes(app.PocketsUIFigure);
            title(app.Ztrace, 'Convolved mean oxygen sink trace (z)')
            app.Ztrace.XTick = [];
            app.Ztrace.YTick = [];
            app.Pocketpos = uiaxes(app.PocketsUIFigure);
            title(app.Pocketpos, 'Pocket position')
            app.Pocketpos.XTick = [];
            app.Pocketpos.YTick = [];
            app.PocketsLabel = uilabel(app.PocketsUIFigure);
            app.PocketsLabel.Text = {'Pockets'; ''};
            app.PocketsPanel = uipanel(app.PocketsUIFigure);
            app.LoadDataButton = uibutton(app.PocketsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PocketListBox = uilistbox(app.PocketsPanel);
            app.PocketListBox.Items = {};
            app.PocketListBox.ValueChangedFcn = createCallbackFcn(app, @PocketListBoxValueChanged, true);
            app.PocketListBox.Value = {};
            app.PreviousButton = uibutton(app.PocketsPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = '▲';
            app.NextButton = uibutton(app.PocketsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = '▼';
            app.ExportDataButton = uibutton(app.PocketsUIFigure, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.YesButton = uibutton(app.PocketsUIFigure, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.PocketsUIFigure, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.IsthisarealpocketLabel = uilabel(app.PocketsUIFigure);
            app.IsthisarealpocketLabel.Text = 'Is this a real pocket?';
            app.StatusEditField = uieditfield(app.PocketsUIFigure, 'text');
            % Show the figure after all components are created
            app.PocketsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxygenDynamics_Sinks_Curation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PocketsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PocketsUIFigure)
        end
    end
end